classdef demo_select_channels < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UITable              matlab.ui.control.Table
        DeviceDropDownLabel  matlab.ui.control.Label
        DeviceDropDown       matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        Devices % the output of the daqlist command. This is used for storing all connected device info
        initial_table % this is used to store the empty table if the user decides to reset and select a new device
    end
    
    methods (Access = private)
        
        
        function buildDAQtable(app,devIDX)
            % this function is used to build the table that displays all
            % the available DAQ connections and configurations.
            
            % filter the devices list to only include the selected
            % device
            devices = app.Devices(devIDX,:);
            
            % get the analog input subsystem information. As far as I
            % can tell, the subsystems are always listed in the same
            % order with analog input always first. 
            analog_input_subsystem = devices.DeviceInfo.Subsystems(1);
            
            % make a list of all the channel names
            channelID_array = cellstr(string(analog_input_subsystem.ChannelNames));
            
            % get the dimension of the channel array
            dimSize_channels = size(channelID_array);
            
            % create an array of booleans that are all false for the
            % user to manually select which channels they want to
            % include in the measurement
            add_array = false(dimSize_channels);
            
            % create an array for the unique/custom channel names when
            % the user decides to add it as an input. Upon creation,
            % they should all be blank.
            name_array = cell(dimSize_channels);
            name_array(:) = {''};
            
            % create an array of the categorical measurement type
            meas_types_available = analog_input_subsystem.MeasurementTypesAvailable;
            measType_array = cell(dimSize_channels);
            measType_array(:) = meas_types_available(1);
            
            % create an array of the categorical terminal configuration
            terminal_config_available = analog_input_subsystem.TerminalConfigsAvailable;
            terminalConfig_array = cell(dimSize_channels);
            % prefer the differential configuration. However, this option
            % may not be available on every DAQ subsystem out there.
            % Therefore, check to see if the config exists first, otherwise
            % default to the 1st element in the array
            pref_config = {'Differential'};
            if any(strcmp(terminal_config_available,pref_config))
                terminalConfig_array(:) = pref_config;
            else
                terminalConfig_array(:) = terminal_config_available(1);
            end
            
            % create an array of the categorical ranges available with this
            % subsystem
            ranges_available = analog_input_subsystem.RangesAvailable;
            range_array = cell(dimSize_channels);
            % build an array of strings that show the available range
            % options to the user
            numRanges = numel(ranges_available);
            range_items = strings(numRanges,1);
            itemsData = cell(numRanges,1);
            for ii = 1:numRanges
                range = analog_input_subsystem.RangesAvailable(ii);
                range_items(ii) = sprintf('%.2f to %.2f %s', range.Min, range.Max, range.Units);
                itemsData{ii} = [range.Min range.Max];
            end
            range_items = cellstr(range_items); 
            % if the -10 to 10 Volts option is available, then set the
            % default terminal config to this value, otherwise use the
            % first availible configuration
            pref_config = {'-10.00 to 10.00 Volts'};
            if any(strcmp(range_items,pref_config))
                range_array(:) = pref_config;
            else
                range_array(:) = range_items(1);
            end
            
            % create an array of the categorical couplings
            couplings_available = analog_input_subsystem.CouplingsAvailable;
            couplings_array = cell(dimSize_channels);
            % prefer the differential configuration. However, this option
            % may not be available on every DAQ subsystem out there.
            % Therefore, check to see if the config exists first, otherwise
            % default to the 1st element in the array
            pref_config = {'DC'};
            if any(strcmp(couplings_available,pref_config))
                couplings_array(:) = pref_config;
            else
                couplings_array(:) = couplings_available(1);
            end
            
            % add all the data to a table
            T = table(add_array,channelID_array,name_array,measType_array,...
                terminalConfig_array,range_array,couplings_array);
            T.measType_array = categorical(T.measType_array,meas_types_available,"Ordinal",true);
            T.terminalConfig_array = categorical(T.terminalConfig_array,cellstr(terminal_config_available),"Ordinal",true);
            T.range_array = categorical(T.range_array,range_items,"Ordinal",true);
            T.couplings_array = categorical(T.couplings_array,cellstr(couplings_available),"Ordinal",true);
            app.UITable.ColumnName = {'Add', 'Channel ID', 'Name','Measurement Type',...
                'Terminal Configuration', 'Range','Coupling'};
            app.UITable.ColumnWidth = {50,'auto','auto','auto','auto','auto','auto'};
            app.UITable.ColumnEditable = [true,false,true,true,true,true,true];
            app.UITable.Data = T;
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % grab the information for the 'empty table' this is used to
            % reset the table back to its original state if the user resets
            % the table
            app.initial_table.Data = app.UITable.Data;
            
            % Get connected devices that have the supported subsystem and measurement types
            devices = daqlist("ni");
            
            % we will access this list of devices and device information in
            % other parts of the app, so we will assign it to a device
            % property
            app.Devices = devices;
            
            % Populate the device drop down list with cell array of composite device names (ID + model)
            % First element is "Select a device"
            deviceDescriptions = cellstr(string({devices.DeviceID}') + " [" + string({devices.Model}') + "]");
            app.DeviceDropDown.Items = ['Select a device'; deviceDescriptions];
            
            % Assign dropdown ItemsData to correspond to device index + 1
            % (first item is not a device)
            app.DeviceDropDown.ItemsData = 1:numel(app.DeviceDropDown.Items)+1;
        end

        % Value changed function: DeviceDropDown
        function DeviceDropDownValueChanged(app, event)
            value = app.DeviceDropDown.Value;
            
            % check to make sure that the device is returning a logical
            % value for the drop down selection
            if ~isempty(value)
                if value == 1
                    % don't build a new table, if a new device is selected,
                    % instead we should reset the table to it's initial
                    % state
                    app.UITable.Data = app.initial_table.Data; 
                else
                    % determine what the selected device's index is. Recasll
                    % that the first element in the array is the 'select
                    % device' option. Therefore, we neecd to remove 1 from the
                    % index to match the device list.
                    devIDX = value-1;
                
                    % run the custom function to build the table
                    buildDAQtable(app,devIDX)
                end
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Add'; 'ChannelID'; 'Name'; 'Measurement Type'; 'Terminal Config'; 'Range'; 'Coupling'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true false true false true true true];
            app.DeviceDropDownLabel = uilabel(app.UIFigure);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.UIFigure);
            app.DeviceDropDown.Items = {'Detecting devices...'};
            app.DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @DeviceDropDownValueChanged, true);
            app.DeviceDropDown.Value = 'Detecting devices...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demo_select_channels

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end